classdef movieViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoviePlayerFigure         matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        SavemovieMenu             matlab.ui.container.Menu
        EncodemovieMenu           matlab.ui.container.Menu
        ViewMenu                  matlab.ui.container.Menu
        DFFMenu                   matlab.ui.container.Menu
        CropMenu                  matlab.ui.container.Menu
        BufferMenu                matlab.ui.container.Menu
        AllowedmemoryMenu         matlab.ui.container.Menu
        FrameDisplayAxes          matlab.ui.control.UIAxes
        PlayPauseButton           matlab.ui.control.StateButton
        BufferSliderLabel         matlab.ui.control.Label
        BufferSlider              matlab.ui.control.Slider
        BufferSizeEditFieldLabel  matlab.ui.control.Label
        BufferSizeEditField       matlab.ui.control.NumericEditField
        LoadingLabel              matlab.ui.control.Label
        LoadingLamp               matlab.ui.control.Lamp
        FrameLabel                matlab.ui.control.Label
        BufferExtentAxes          matlab.ui.control.UIAxes
        ForwardOneButton          matlab.ui.control.Button
        BackwardOneButton         matlab.ui.control.Button
        GotoframeLabel            matlab.ui.control.Label
        FrameNumberEditField      matlab.ui.control.NumericEditField
        FrameSliderLabel          matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        StrideEditFieldLabel      matlab.ui.control.Label
        StrideEditField           matlab.ui.control.NumericEditField
        GoButton                  matlab.ui.control.Button
        HighClimSlider            matlab.ui.control.Slider
        LowClimSlider             matlab.ui.control.Slider
        LowClimLabel              matlab.ui.control.Label
        HighClimLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        movie_path = ''; % Path to the hdf5 file containing the movie
        dataset % The path to the dataset within the hdf5 file
        frames_per_second % Frames per second at which to play/encode the movie
        info % The result from h5info on the dataset
        total_frames % The total number of frames in the movie
        
        buffer = []; % Numeric buffer containing a slice of the movie to be played/displayed
        buffer_size % number of frames in the buffer
        frame_size % The size of a frame in the movie
        %reload_size = 1; % number of frames to reload when the buffer completes playing
        allowed_memory = 3e9;  % the allowed memory for the buffer (in bytes)
        
        frame_counter = int64(1); % where in the movie are we
        %buffer_counter = 1;% where in the buffer are we
        buffer_offset = int64(1); % how offset is the buffer
        buffer_extent; % showing which frames are in the buffer
        time_to_load_buffer = -1; % time it took to load a buffer, negative if unknown
        is_video_mode = false; % are we currently playing a video?
        my_imagesc;
        clim = [];
        stride = 1;
        play_locked = false;
    end

    methods (Access = private)
    
        function load_buffer(app, is_video_mode)
            if isempty(app.movie_path)
                return;
            end
            if is_video_mode
                start_frame = app.frame_counter;
            else
                start_frame = min(app.total_frames, max(floor((app.frame_counter - app.buffer_size/2)/app.stride)*app.stride+mod(app.frame_counter,app.stride), 1));
            end
            end_frame = min(start_frame + app.buffer_size - 1, app.total_frames);
            read_amount = end_frame - start_frame + 1;
            
            if app.time_to_load_buffer < 0
                app.LoadingLabel.Text = 'Loading...';
            else
                dur = duration(0, 0, app.time_to_load_buffer * read_amount / app.stride);
                dur.Format = 'mm:ss.SSS';
                app.LoadingLabel.Text = sprintf('ETA %s', dur);
            end
            app.LoadingLamp.Color = [0 1 0];
            drawnow;


            my_ticker = tic;
            app.buffer = h5read(app.movie_path, app.dataset, double([1 1 start_frame]), double([app.info.Dataspace.Size(1:2), ceil(double(read_amount)/app.stride)]), [1 1 app.stride]);
            app.time_to_load_buffer = toc(my_ticker)/size(app.buffer,3);
            app.buffer_extent = logical(sparse([], [], [], 1, app.total_frames, app.buffer_size));
            extent_end_frame = start_frame + app.stride*(size(app.buffer,3)-1);
            
            
            %app.stride*floor((end_frame - start_frame)/app.stride);
            app.buffer_extent(start_frame:extent_end_frame) = true; %not indicating stride in this!
            imagesc(app.BufferExtentAxes, app.buffer_extent);
            app.buffer_offset = start_frame;
            %assert(app.buffer_extent(app.frame_counter), 'The current frame is not in the just-loaded buffer.');
            
            dur = duration(0, 0, app.time_to_load_buffer * size(app.buffer,3));
            dur.Format = 'mm:ss.SSS';
            app.LoadingLabel.Text = sprintf('%s', dur);
            %app.LoadingLabel.Text = sprintf('%1.1gs', app.time_to_load_buffer);
            app.LoadingLamp.Color = [0 0 0];
            app.BufferSlider.Limits = double([app.buffer_offset, app.buffer_offset + app.buffer_size - 1]);
            assert(app.frame_counter >= app.BufferSlider.Limits(1) && app.frame_counter <= app.BufferSlider.Limits(2),...
                'not within buff slider limits: [%d-%d], frame was %d', app.BufferSlider.Limits(1), app.BufferSlider.Limits(2), app.frame_counter);
            app.BufferSlider.Value = double(app.frame_counter);
            app.BufferSlider.MajorTicksMode = 'auto';
            app.BufferSlider.Enable = true;
            app.BufferSizeEditFieldLabel.Text = sprintf('Buffer Size [%.2fGB]', app.info.Datatype.Size*numel(app.buffer)/1e9);
            drawnow;
        end
    
        function draw_frame(app)
            persistent max_value min_value;
            %TODO make it really function
            fprintf('Displaying frame: %d\n', app.frame_counter);
            if ~app.buffer_has_frame(app.frame_counter)
                app.load_buffer(app.is_video_mode);
            end
            app.my_imagesc.CData = app.fetch_frame(app.frame_counter);
            
            if ~isempty(max_value)
                max_value = max(max_value, max(max(app.my_imagesc.CData)));
            else
                max_value = max(max(app.my_imagesc.CData));
            end
            if ~isempty(min_value)
                min_value = min(min_value, min(min(app.my_imagesc.CData)));
            else
                min_value = min(min(app.my_imagesc.CData));
            end
            if ~isempty(max_value) && ~isempty(min_value) && (min_value < max_value)
                fprintf('%f - %f\n', min_value, max_value);
                app.HighClimSlider.Limits(1) = min_value;
                app.HighClimSlider.Limits(2) = max_value;
                
                %app.HighClimSlider.MajorTicksMode = 'auto';
                %app.HighClimSlider.MajorTickLabelsMode = 'auto';
                app.LowClimSlider.Limits(1) = min_value;
                app.LowClimSlider.Limits(2) = max_value;
                %app.HighClimSlider.Limits = [min_value, max_value];
                %app.LowClimSlider.Limits = [min_value, max_value];
            end
            if numel(app.clim) == 2
                caxis(app.FrameDisplayAxes, app.clim);
            elseif ~isempty(max_value) && ~isempty(min_value) && (min_value < max_value)
                caxis(app.FrameDisplayAxes, [min_value, max_value]);
                app.HighClimSlider.Value = app.HighClimSlider.Limits(2);
                app.LowClimSlider.Value = app.LowClimSlider.Limits(1);
            end
            app.BufferSlider.Value = double(app.frame_counter);
            drawnow;
        end
        
        function has_frame = buffer_has_frame(app, frame_index)
            assert((frame_index >= 1) && (frame_index <= app.total_frames),...
                'Frame index is out of bounds: the range is 1 to %d, asked for %d', app.total_frames, frame_index);
            %fprintf('Problematic value: %d\n', frame_index);
            fprintf('Buffer offset: %d, size %d, stride %d, requested frame %d\n', app.buffer_offset, size(app.buffer,3), app.stride, frame_index);
            has_frame = app.buffer_extent(frame_index);
        end
        
        function frame = fetch_frame(app, frame_index)
            assert(app.buffer_has_frame(frame_index));
            index_within_buffer = floor((frame_index - app.buffer_offset)/app.stride) + 1;
            assert(index_within_buffer >= 1 && index_within_buffer <= size(app.buffer,3),...
                'index_within_buffer is out of bounds of the buffer: %d, max is: %d', index_within_buffer, size(app.buffer,3));
            frame = app.buffer(:,:,index_within_buffer);
        end
    
%         function increment_frame(app)
%             %TODO make it really function
%             app.change_frame(app.frame_counter + 1);
%         end
    
        function set_lock(app, flag, vidmode)
            assert(islogical(flag), 'argument must be logical');
            if ~exist('vidmode', 'var')
                app.is_video_mode = flag;
            else
                app.is_video_mode = vidmode;
            end
            app.FrameSlider.Enable = ~flag;
            app.BufferSlider.Enable = ~flag;
            app.BufferSizeEditField.Editable = ~flag;
        end
        
        function change_frame(app, frame)
            app.frame_counter = max(1,min(frame, app.total_frames));
            app.FrameSlider.Value = double(app.frame_counter);
            %if app.BufferSlider.Enable && (app.BufferSlider.Limits(1) <= app.frame_counter) && (app.frame_counter <= app.BufferSlider.Limits(2))
            %    app.BufferSlider.Value = double(app.frame_counter);
            %end
            app.FrameLabel.Text = sprintf('Frame number %d', int64(app.frame_counter));
        end
        
        function valid = validPathAndData(app, movie_path_and_dataset)
            parts_ = split(movie_path_and_dataset, ':');
            if numel(parts_)~=2
                valid = false;
                return;
            end
            if ~exist(parts_{1}, 'file')
                valid = false;
                return;
            end
            app.movie_path = parts_{1};
            app.dataset = parts_{2};
            try app.info = h5info(app.movie_path, app.dataset);
            catch e
                disp(e);
                %error('Unable to load the movie as %s', movie_path_and_dataset);
                valid = false;
                return;
            end
            valid = true;
        end
        
        function [path_dataset, cancel] = ask_for_path_dataset(app, messages)
            cancel = false;
            [file_,path_] = uigetfile({'*.*';'*.h5';'*.hdf5';'*.hdf'},...
                messages{1}, '../../');
            if isempty(file_)
                closereq;
                cancel = true;
                path_dataset = '';
                return;
            end
            tot_path_ = fullfile(path_, file_);
            dataset_name_ = inputdlg('Enter dataset name:', messages{1}, [1 35], {'/Data/Images'});
            if isempty(dataset_name_) || numel(dataset_name_) < 1
                path_dataset = '';
                cancel = true;
                return;
            end
            dataset_name_ = dataset_name_{1};
            path_dataset = [tot_path_ ':' dataset_name_];
        end
    
        function valid = validFps(~, fps)
            valid = isscalar(fps) && fps > 0 && round(fps) == fps;
        end
    
        function [fps, cancel] = ask_for_fps(app, messages, prompt, default_val)
            if ~exist('prompt', 'var')
                prompt = 'Enter fps (Hz):';
            end
            if ~exist('default_val', 'var')
                default_val = '20';
            end
            cancel = false;
            try
                str_input = inputdlg(prompt, messages{1}, [1 35], {default_val});
                if isempty(str_input)
                    closereq;
                    cancel = true;
                    fps = [];
                    return;
                end
                fps = str2double(str_input);
            catch
                fps = [];
                return;
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, movie_path_and_dataset, fps)
            app.MoviePlayerFigure.Visible = false;
            if ~exist('movie_path_and_dataset', 'var')
                movie_path_and_dataset = '';
            end
            if ~exist('fps', 'var')
                fps = '';
            end
            
            repeat_ = false;
            while ~app.validPathAndData(movie_path_and_dataset)
                if repeat_
                    messages = {'Bad input. Select valid movie file and input a valid dataset within it.', 'BAD INPUT!!!'};
                else
                    messages = {'Please select a valid movie file and input a valid dataset within it.', 'Input required'};
                end
                [movie_path_and_dataset, cancel] = app.ask_for_path_dataset(messages);
                if cancel
                    %closereq;
                    return;
                end
                repeat_ = true;
            end
            repeat_ = false;
            while ~app.validFps(fps)
                if repeat_
                    messages = {'Bad input. Positive integer only.', 'BAD INPUT!!!'};
                else
                    messages = {'Please input the fps.', 'Input required'};
                end
                [fps, cancel] = app.ask_for_fps(messages);
                if cancel
                    %closereq;
                    return;
                end
                repeat_ = true;
            end


%             if ~exist('movie_path_and_dataset', 'var')
%                 dataset_name_ = '';
%                 
%                 
%                 [file_,path_] = uigetfile({'*.*';'*.h5';'*.hdf5';'*.hdf'},...
%                           'File Selector');
%                 tot_path_ = fullfile(path_, file_);
%                 while isempty(regexp(dataset_name_, '^/.+', 'once'))
%                     try
%                         dataset_name_ = inputdlg('Enter dataset name:', 'Dataset', [1 35], {'/Data/DFF'});
%                     catch
%                         uialert(uifigure, 'Bad input. Select valid movie file and input a valid dataset within it.', 'BAD INPUT!!!');
%                     end
%                 end
%                 movie_path_and_dataset = [tot_path_ ':' dataset_name_{1}];
%             end
%             
%             
%             if ~exist('fps', 'var')
%                 fps = [];
%                 while ~isscalar(fps) || fps <= 0 || round(fps) ~= fps
%                     try fps = str2double(inputdlg('Enter fps (Hz):', 'Input fps', [1 35], {'20'}));
%                     catch
%                         uialert(uifigure, 'Bad input. Positive integer only.', 'BAD INPUT!!!');
%                     end
%                 end
%             end
            app.MoviePlayerFigure.Visible = true;
            parts_ = split(movie_path_and_dataset, ':');
            assert(numel(parts_)==2, 'Must give a path as /some/path/movie.h5:/dataset/name. The given input %s does not match this.', movie_path_and_dataset);
            assert(exist(parts_{1}, 'file')~=0, 'Provided file %s was not found', parts_{1});
            app.movie_path = parts_{1};
            app.dataset = parts_{2};
            try app.info = h5info(app.movie_path, app.dataset);
            catch e
                disp(e);
                error('Unable to load the movie as %s', movie_path_and_dataset);
            end
            app.frame_size = app.info.Dataspace.Size(1:2);
            app.total_frames = app.info.Dataspace.Size(3);
            frame_memory = app.info.Datatype.Size * prod(app.frame_size);
            %app.allowed_memory = 1e9; %1GB by default, maybe change later
            app.buffer_size = ceil(app.allowed_memory / frame_memory);
            app.BufferSizeEditField.Value = app.buffer_size;
            app.BufferSizeEditFieldLabel.Text = sprintf('Buffer Size [%.2fGB]', frame_memory*app.buffer_size/1e9);
            app.frames_per_second = fps;
            %app.reload_size = 1; % maybe allow to change later
            %app.buffer = h5read(app.movie_path, app.dataset, [1 1 1], [app.info.Dataspace.Size(1:2), app.buffer_size]);
            %app.load_buffer();
            app.my_imagesc = imagesc(app.FrameDisplayAxes, zeros(app.info.Dataspace.Size(1:2)));
            colorbar(app.FrameDisplayAxes);
            xlim(app.FrameDisplayAxes, [-Inf Inf]);
            ylim(app.FrameDisplayAxes, [-Inf Inf]);
            axis(app.FrameDisplayAxes, 'image');
            [~, mov_, ext_] = fileparts(app.movie_path);
            title(app.FrameDisplayAxes, [mov_ ext_ ':' app.dataset], 'Interpreter', 'none');
            app.FrameSlider.Limits = [1 app.total_frames];
            app.BufferSlider.Enable = false;
            app.buffer_extent = logical(sparse([], [], [], 1, app.total_frames, app.buffer_size));
            
            imagesc(app.BufferExtentAxes, app.buffer_extent);
            colormap(app.BufferExtentAxes, 'winter');
            axis(app.BufferExtentAxes, 'off');
            
        end

        % Callback function: BufferSlider, FrameSlider
        function FrameSliderValueChanged(app, event)
            if isempty(app.movie_path)
                return;
            end
            value = event.Source.Value;
            app.change_frame(round(value));
            app.draw_frame();
        end

        % Value changed function: PlayPauseButton
        function play_pause_clicked(app, event)
            %persistent play_locked;
            if isempty(app.movie_path)
                return;
            end
            if ~isempty(app.play_locked) && app.play_locked
                fprintf('Already locked, doing nothing\n');
                return;
            end
            app.play_locked = true;
            %value = app.PlayPauseButton.Value; %true means playing, false means paused
            get_value = @() app.PlayPauseButton.Value;
            while get_value()
                app.set_lock(true);
                app.draw_frame();
                pause(1/app.frames_per_second);
                app.change_frame(app.frame_counter + app.stride);
            end
            fprintf('Pausing!\n');
            app.set_lock(false);
            app.play_locked = false;
        end

        % Button pushed function: ForwardOneButton
        function NextFrame(app, event)
            if isempty(app.movie_path)
                return;
            end
            app.change_frame(app.frame_counter + app.stride);
            app.draw_frame();
            %app.ForwardOneButton.Value = false;
        end

        % Button pushed function: BackwardOneButton
        function PrevFrame(app, event)
            if isempty(app.movie_path)
                return;
            end
            app.change_frame(app.frame_counter - app.stride);
            app.draw_frame();
            %app.BackwardOneButton.Value = false;
        end

        % Close request function: MoviePlayerFigure
        function closeFcn(app, event)
            app.PlayPauseButton.Value = false;
            if ~app.play_locked
                delete(app)
            end
        end

        % Value changed function: BufferSizeEditField
        function changeBufferSize(app, event)
            if isempty(app.movie_path)
                return;
            end
            value = app.BufferSizeEditField.Value;
            if ~isscalar(value) || value <= 0 || round(value) ~= value
                return;
            end
            app.buffer_size = value;
            frame_memory = app.info.Datatype.Size * prod(app.frame_size);
            app.allowed_memory = app.buffer_size * frame_memory;
            app.BufferSizeEditFieldLabel.Text = sprintf('Buffer Size [%.2fGB]', frame_memory*app.buffer_size/app.stride/1e9);
        end

        % Value changed function: StrideEditField
        function StrideEditFieldValueChanged(app, event)
            value = app.StrideEditField.Value;
            if ~isscalar(value) || value <= 0 || round(value) ~= value
                return;
            end
            app.stride = value;
        end

        % Value changed function: FrameNumberEditField
        function FrameNumberEditFieldValueChanged(app, event)
            value = app.FrameNumberEditField.Value;
            if ~isscalar(value) || value <= 0 || round(value) ~= value || value > app.total_frames
                return;
            end
            app.frame_counter = value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if isempty(app.movie_path)
                return;
            end
            app.change_frame(app.frame_counter);
            app.draw_frame();
        end

        % Value changed function: HighClimSlider
        function HighClimSliderValueChanged(app, event)
            value = app.HighClimSlider.Value;
            app.clim = sort([app.LowClimSlider.Value, value]);
        end

        % Value changed function: LowClimSlider
        function LowClimSliderValueChanged(app, event)
            value = app.LowClimSlider.Value;
            app.clim = sort([value, app.HighClimSlider.Value]);
        end

        % Menu selected function: AllowedmemoryMenu
        function AllowedmemoryMenuSelected(app, event)
            repeat_ = false;
            allowed_mem = 0;
            while allowed_mem <= 0
                if repeat_
                    messages = {'Bad input. Positive float only.', 'BAD INPUT!!!'};
                else
                    messages = {'Please input the allowed memory (in GB).', 'Input required'};
                end
                [allowed_mem, cancel] = app.ask_for_fps(messages, 'Enter memory allowance for the buffer (GB):', '3');
                if cancel
                    %closereq;
                    return;
                end
                repeat_ = true;
            end
            app.BufferSizeEditField.Value = floor(allowed_mem*1e9 / (app.info.Datatype.Size * prod(app.frame_size)));
            app.changeBufferSize([]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoviePlayerFigure = uifigure;
            app.MoviePlayerFigure.Name = 'Movie Player';
            app.MoviePlayerFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.FileMenu = uimenu(app.MoviePlayerFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SavemovieMenu = uimenu(app.FileMenu);
            app.SavemovieMenu.Text = 'Save movie';
            app.EncodemovieMenu = uimenu(app.FileMenu);
            app.EncodemovieMenu.Text = 'Encode movie';
            app.ViewMenu = uimenu(app.MoviePlayerFigure);
            app.ViewMenu.Text = 'View';
            app.DFFMenu = uimenu(app.ViewMenu);
            app.DFFMenu.Text = 'DFF';
            app.CropMenu = uimenu(app.ViewMenu);
            app.CropMenu.Text = 'Crop';
            app.BufferMenu = uimenu(app.MoviePlayerFigure);
            app.BufferMenu.Text = 'Buffer';
            app.AllowedmemoryMenu = uimenu(app.BufferMenu);
            app.AllowedmemoryMenu.MenuSelectedFcn = createCallbackFcn(app, @AllowedmemoryMenuSelected, true);
            app.AllowedmemoryMenu.Text = 'Allowed memory';
            app.FrameDisplayAxes = uiaxes(app.MoviePlayerFigure);
            title(app.FrameDisplayAxes, 'Movie Player')
            app.PlayPauseButton = uibutton(app.MoviePlayerFigure, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @play_pause_clicked, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.BufferSliderLabel = uilabel(app.MoviePlayerFigure);
            app.BufferSliderLabel.Text = 'Buffer';
            app.BufferSlider = uislider(app.MoviePlayerFigure);
            app.BufferSlider.Limits = [1 10];
            app.BufferSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.BufferSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.BufferSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.BufferSlider.MinorTicks = [];
            app.BufferSlider.Value = 1;
            app.BufferSizeEditFieldLabel = uilabel(app.MoviePlayerFigure);
            app.BufferSizeEditFieldLabel.Text = 'Buffer Size';
            app.BufferSizeEditField = uieditfield(app.MoviePlayerFigure, 'numeric');
            app.BufferSizeEditField.Limits = [0 Inf];
            app.BufferSizeEditField.RoundFractionalValues = 'on';
            app.BufferSizeEditField.ValueChangedFcn = createCallbackFcn(app, @changeBufferSize, true);
            app.LoadingLabel = uilabel(app.MoviePlayerFigure);
            app.LoadingLabel.Text = 'Loading...';
            app.LoadingLamp = uilamp(app.MoviePlayerFigure);
            app.FrameLabel = uilabel(app.MoviePlayerFigure);
            app.FrameLabel.Text = 'Frame number 123';
            app.BufferExtentAxes = uiaxes(app.MoviePlayerFigure);
            app.ForwardOneButton = uibutton(app.MoviePlayerFigure, 'push');
            app.ForwardOneButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrame, true);
            app.ForwardOneButton.Text = '-->';
            app.BackwardOneButton = uibutton(app.MoviePlayerFigure, 'push');
            app.BackwardOneButton.ButtonPushedFcn = createCallbackFcn(app, @PrevFrame, true);
            app.BackwardOneButton.Text = '<--';
            app.GotoframeLabel = uilabel(app.MoviePlayerFigure);
            app.GotoframeLabel.Text = 'Go to frame:';
            app.FrameNumberEditField = uieditfield(app.MoviePlayerFigure, 'numeric');
            app.FrameNumberEditField.ValueDisplayFormat = '%.0f';
            app.FrameNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FrameNumberEditFieldValueChanged, true);
            app.FrameSliderLabel = uilabel(app.MoviePlayerFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.MoviePlayerFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.StrideEditFieldLabel = uilabel(app.MoviePlayerFigure);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.StrideEditField = uieditfield(app.MoviePlayerFigure, 'numeric');
            app.StrideEditField.ValueChangedFcn = createCallbackFcn(app, @StrideEditFieldValueChanged, true);
            app.StrideEditField.Value = 1;
            app.GoButton = uibutton(app.MoviePlayerFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HighClimSlider = uislider(app.MoviePlayerFigure);
            app.HighClimSlider.Limits = [-100 100];
            app.HighClimSlider.ValueChangedFcn = createCallbackFcn(app, @HighClimSliderValueChanged, true);
            app.LowClimSlider = uislider(app.MoviePlayerFigure);
            app.LowClimSlider.Limits = [-100 100];
            app.LowClimSlider.ValueChangedFcn = createCallbackFcn(app, @LowClimSliderValueChanged, true);
            app.LowClimLabel = uilabel(app.MoviePlayerFigure);
            app.LowClimLabel.Text = 'Low Clim';
            app.HighClimLabel = uilabel(app.MoviePlayerFigure);
            app.HighClimLabel.Text = 'High Clim';
        end
    end

    methods (Access = public)

        % Construct app
        function app = movieViewer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoviePlayerFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoviePlayerFigure)
        end
    end
end